classdef Mag_Field_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SFieldJeffreyMEdeUIFigure  matlab.ui.Figure
        xFieldTable                matlab.ui.control.Table
        xFieldAxes                 matlab.ui.control.UIAxes
        SweepFieldButton           matlab.ui.control.Button
        SaveSettingsButton         matlab.ui.control.Button
        LoadSettingsButton         matlab.ui.control.Button
        yFieldTable                matlab.ui.control.Table
        yFieldAxes                 matlab.ui.control.UIAxes
        zFieldTable                matlab.ui.control.Table
        zFieldAxes                 matlab.ui.control.UIAxes
        FieldButton                matlab.ui.control.Button
        SaveSweepButton            matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        DropDownLabel              matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        ToleranceEditFieldLabel    matlab.ui.control.Label
        ToleranceEditField         matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        xlimits = {}; % x field sweep rate limits
        ylimits = {}; % y field sweep rate limits
        zlimits = {}; % z field sweep rate limits
    end

    methods (Access = public)
    
        function fast_to_field(app, field, B_target, instr)
            % Get limits
            if (field == "x")
                limits = app.xlimits;
            elseif (field == "y")
                limits = app.ylimits
            else
                limits = app.zlimits
            end
            
            % Get magnetic field value
            fprintf(instr,'%s\n', 'IMAG?');
            fscanf(instr);
            % Use regex to extract number from string
            B = str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            
            % Check if we are already at the field
            if (abs(B-B_target) <= app.ToleranceEditField.Value)
                return ;
            end
            
            % Fast sweep to target field
            if (B > B_target)
                fprintf(instr,'%s\n', strcat('ULIM ', num2str(B_target)));
                fscanf(instr);
                fprintf(instr,'%s\n', 'SWEEP UP FAST');
                fscanf(instr);
            else
                fprintf(instr,'%s\n', strcat('LLIM ', num2str(B_target)));
                fscanf(instr);
                fprintf(instr,'%s\n', 'SWEEP DOWN FAST');
                fscanf(instr);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialise tables with a row of empty cells
            app.xFieldTable.Data = {'' '' ''};
            app.yFieldTable.Data = {'' '' ''};
            app.zFieldTable.Data = {'' '' ''};
            
            % Load sweep rate limits
            fid = fopen('limits.txt');
            
            % Read limits from file
            while ~feof(fid)
                fileline = fgetl(fid);
                
                % Find which coil limits are for
                if contains(fileline,"%")
                    limits = fileline;
                    
                % Update x limits
                elseif (limits == "%x")
                    
                    vals = textscan(fileline,'%f');
                    app.xlimits = [app.xlimits; vals{1}'];
                    
                % Update y limits
                elseif (limits == "%y")
                    
                    vals = textscan(fileline,'%f');
                    app.ylimits = [app.ylimits; vals{1}'];
                    
                % Update z limits
                elseif (limits == "%z")
                    
                    vals = textscan(fileline,'%f');
                    app.zlimits = [app.zlimits; vals{1}];
                    
                elseif (limits == "%xmax")
                    
                    vals = textscan(fileline,'%f');
                    app.xlimits = [app.xlimits; vals{1}];
                    
                elseif (limits == "%ymax")
                    
                    vals = textscan(fileline,'%f');
                    app.ylimits = [app.ylimits; vals{1}];
                    
                elseif (limits == "%zmax")
                    
                    vals = textscan(fileline,'%f');
                    app.zlimits = [app.zlimits; vals{1}];
                    
                elseif (limits == "%xItoB")
                    
                    vals = textscan(fileline,'%f');
                    app.xlimits = [app.xlimits; vals{1}];
                    
                elseif (limits == "%yItoB")
                    
                    vals = textscan(fileline,'%f');
                    app.ylimits = [app.ylimits; vals{1}];
                    
                elseif (limits == "%zItoB")
                    
                    vals = textscan(fileline,'%f');
                    app.zlimits = [app.zlimits; vals{1}];
                end
            end
            
            % Number of rows in limits
            [x_limrows, c] = size(app.xFieldTable.Data);
            [y_limrows, c] = size(app.yFieldTable.Data);
            [z_limrows, c] = size(app.zFieldTable.Data);
            
            % Convert limits' currents to G
            for i = 1:(x_limrows-1)
                app.xlimits{i}(1) = app.xlimits{i}(1)*app.xlimits{end}(2)
                app.xlimits{i}(2) = app.xlimits{i}(2)*app.xlimits{end}(2)
            end
            app.xlimits{end}(1) = app.xlimits{end}(1)*1000
            
            for i = 1:(y_limrows-1)
                app.ylimits{i}(1) = app.ylimits{i}(1)*app.ylimits{end}(2)
                app.ylimits{i}(2) = app.ylimits{i}(2)*app.ylimits{end}(2)
            end
            app.ylimits{end}(1) = app.ylimits{end}(1)*1000
            
            for i = 1:(z_limrows-1)
                app.zlimits{i}(1) = app.zlimits{i}(1)*app.zlimits{end}(2)
                app.zlimits{i}(2) = app.zlimits{i}(2)*app.zlimits{end}(2)
            end
            app.zlimits{end}(1) = app.zlimits{end}(1)*1000
            
            fclose(fid);       
        end

        % Button pushed function: SweepFieldButton
        function sweep_fields(app, event)
            
            % Create serial port objects
            x = serial('COM3', 'baudrate',9600);
            fopen(x);
            y = serial('COM3', 'baudrate',9600);
            fopen(y);
            z = serial('COM3', 'baudrate',9600);
            fopen(z);
            
            fprintf(z,'%s\n', 'IOUT?');
            fscanf(z);
            
            % Number of rows in tables
            [x_rows, c] = size(app.xFieldTable.Data);
            [y_rows, c] = size(app.yFieldTable.Data);
            [z_rows, c] = size(app.zFieldTable.Data);
            
            % Number of rows in limits
            [x_limrows, c] = size(app.xFieldTable.Data);
            [y_limrows, c] = size(app.yFieldTable.Data);
            [z_limrows, c] = size(app.zFieldTable.Data);
            
            % Find the maximum sweep rates and check for overshooting of local sweep limits
            x_maxO = 0;
            y_maxO = 0;
            z_maxO = 0;
            x_Ofunc = "";
            y_Ofunc = "";
            z_Ofunc = "";
            x_Otime = 0;
            y_Otime = 0;
            z_Otime = 0;
            for i = 1:x_rows                
                % Get function describing magnetic field
                xstr = app.xFieldTable.Data{i};
                xfun = str2func(['@(t)',xstr]);
                
                % Number of steps
                x_num = str2num(app.xFieldTable.Data{i+2*x_rows};
                
                % Calculate timesteps
                x_tstep = str2num(app.xFieldTable.Data{i+x_rows})/x_num);
                rx_tstep = 1/x_tstep;
                
                % Check if any x sweep rates are higher than the local maximum
                % Record the highest x overshoot of a local maximum, if it occurs
                B_prev = xfun(0);
                for j = 1:x_num
                    
                    % Check B field is below maximum
                    B_target = xfun(j*x_tstep);
                    if (B_target > app.x_limits{end}(1))
                        % create fatal error message
                        errordlg(strcat("X Target field of ", num2str(B_target),"G exceeds maximum of ",...
                            app.x_limits{end}(1), "G.", "\nDetails: ", xstr, " for t = ", num2str(j*x_tstep),...
                            "s."), "Fatal Error")
                        return ;
                    end
                    
                    % Calculate rate
                    rate = abs((B_target-B_prev)*rx_tstep);
                    
                    % Check if it exceeds local maximum
                    for i = 1:xlimrows
                        
                        % Check if this is the limit applicable to this field
                        
                        if (max(abs(B_prev),abs(B_target)) <= app.xlimits{i}(1))
                            
                            % Check for overshoot
                            if (abs(rate) > app.xlimits{i}(2))
                                
                                O = rate/app.xlimits{i}(2);
                                
                                % Check if this is the highest overshoot
                                if (O > x_maxO)
                                    
                                    x_maxO = O;
                                    x_Ofunc = xstr;
                                    x_Otime = j*x_tstep;
                                end 
                            end
                        end
                    end
                end
            end
            
            for i = 1:y_rows                
                % Get function describing magnetic field
                ystr = app.yFieldTable.Data{i};
                yfun = str2func(['@(t)',ystr]);
            
                % Number of steps
                y_num = str2num(app.yFieldTable.Data{i+2*y_rows};
                
                % Calculate timestep
                y_tstep = str2num(app.yFieldTable.Data{i+y_rows})/y_num);
                ry_tstep = 1/y_tstep;
                
                % Check if any y sweep rates are higher than the local maximum
                % Record the highest y overshoot of a local maximum, if it occurs
                B_prev = yfun(0);
                for j = 1:y_num
                    
                    % Check B field is below maximum
                    B_target = yfun(j*y_tstep);
                    if (B_target > app.y_limits{end}(1))
                        % create fatal error message
                        errordlg(strcat("Y Target field of ", num2str(B_target),"G exceeds maximum of ",...
                            app.y_limits{end}(1), "G.", "\nDetails: ", ystr, " for t = ", num2str(j*y_tstep),...
                            "s."), "Fatal Error")
                        return ;
                    end
                    
                    % Calculate rate
                    rate = abs((B_target-B_prev)*ry_tstep);
                    
                    % Check if it exceeds local maximum
                    for i = 1:ylimrows
                        
                        % Check if this is the limit applicable to this field
                        
                        if (max(abs(B_prev),abs(B_target)) <= app.ylimits{i}(1))
                            
                            % Check for overshoot
                            if (abs(rate) > app.ylimits{i}(2))
                                
                                O = rate/app.ylimits{i}(2);
                                
                                % Check if this is the highest overshoot
                                if (O > y_maxO)
                                    
                                    y_maxO = O;
                                    y_Ofunc = ystr;
                                    y_Otime = j*y_tstep;
                                end 
                            end
                        end
                    end
                end
            end
            
            for i = 1:z_rows                
                % Get function describing magnetic field
                zstr = app.zFieldTable.Data{i};
                zfun = str2func(['@(t)',zstr]);
            
                % Number of steps
                z_num = str2num(app.zFieldTable.Data{i+2*z_rows};
                
                % Calculate timestep
                z_tstep = str2num(app.zFieldTable.Data{i+z_rows})/z_num);
                rz_tstep = 1/z_tstep;
                
                % Check if any z sweep rates are higher than the local maximum
                % Record the highest z overshoot of a local maximum, if it occurs
                B_prev = zfun(0);
                for j = 1:z_num
                    
                    % Check B field is below maximum
                    B_target = zfun(j*z_tstep);
                    if (B_target > app.z_limits{end}(1))
                        % create fatal error message
                        errordlg(strcat("Z target field of ", num2str(B_target),"G exceeds maximum of ",...
                            app.z_limits{end}(1), "G.", "\nDetails: ", zstr, " for t = ", num2str(j*z_tstep),...
                            "s."), "Fatal Error")
                        return ;
                    end
                    
                    % Calculate rate
                    rate = abs((B_target-B_prev)*rz_tstep);
                    
                    % Check if it exceeds local maximum
                    for i = 1:zlimrows
                        
                        % Check if this is the limit applicable to this field
                        
                        if (max(abs(B_prev),abs(B_target)) <= app.zlimits{i}(1))
                            
                            % Check for overshoot
                            if (abs(rate) > app.zlimits{i}(2))
                                
                                O = rate/app.zlimits{i}(2);
                                
                                % Check if this is the highest overshoot
                                if (O > z_maxO)
                                    
                                    z_maxO = O;
                                    z_Ofunc = zstr;
                                    z_Otime = j*z_tstep;
                                end 
                            end
                        end
                    end
                end
            end
            
            % Go to start fields as fast as possibe
            fast_to_field(app, 'x', xfun(0), x);
            fast_to_field(app, 'y', xfun(0), y);
            fast_to_field(app, 'z', xfun(0), z);
            
            % Calculate sweep rates
            str2num(app.xFieldTable.Data{3+2*x_rows})
            x_fields = zeros(x_rows, )
            arrayfun(xfun, 0:str2num(app.xFieldTable.Data{3+x_rows})
            
            % Shut down serial port objects
            fclose(z); 
        end

        % Cell edit callback: xFieldTable
        function xFieldTableCellEdit(app, event)
            
            % Count number of rows
            [num_rows, c] = size(app.xFieldTable.Data);
            
            % Check if all of the last row's cells have been filled
            for i = [event.Indices(1) event.Indices(1)+num_rows event.Indices(1)+2*num_rows]
                if (app.xFieldTable.Data{i} == "")
                    return ;
                end
            end
            % If all the cells have been filled, add a new row
            app.xFieldTable.Data = [app.xFieldTable.Data; {'' '' ''}];
        end

        % Cell edit callback: yFieldTable
        function yFieldTableCellEdit(app, event)
            % Count number of rows
            [num_rows, c] = size(app.yFieldTable.Data);
            
            % Check if all of the last row's cells have been filled
            for i = [event.Indices(1) event.Indices(1)+num_rows event.Indices(1)+2*num_rows]
                if (app.yFieldTable.Data{i} == "")
                    return ;
                end
            end
            % If all the cells have been filled, add a new row
            app.yFieldTable.Data = [app.yFieldTable.Data; {'' '' ''}];
        end

        % Cell edit callback: zFieldTable
        function zFieldTableCellEdit(app, event)
            % Count number of rows
            [num_rows, c] = size(app.zFieldTable.Data);
            
            % Check if all of the last row's cells have been filled
            for i = [event.Indices(1) event.Indices(1)+num_rows event.Indices(1)+2*num_rows]
                if (app.zFieldTable.Data{i} == "")
                    return ;
                end
            end
            % If all the cells have been filled, add a new row
            app.zFieldTable.Data = [app.zFieldTable.Data; {'' '' ''}];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SFieldJeffreyMEdeUIFigure = uifigure;
            app.SFieldJeffreyMEdeUIFigure.Name = 'SField - Jeffrey M. Ede';
            app.xFieldTable = uitable(app.SFieldJeffreyMEdeUIFigure);
            app.xFieldTable.ColumnName = {'Bx(t) in G'; 'Time (s)'; 'Steps'};
            app.xFieldTable.RowName = {};
            app.xFieldTable.ColumnEditable = true;
            app.xFieldTable.CellEditCallback = createCallbackFcn(app, @xFieldTableCellEdit, true);
            app.xFieldAxes = uiaxes(app.SFieldJeffreyMEdeUIFigure);
            xlabel(app.xFieldAxes, 'Time (s)')
            ylabel(app.xFieldAxes, 'x field (kG)')
            app.SweepFieldButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.SweepFieldButton.ButtonPushedFcn = createCallbackFcn(app, @sweep_fields, true);
            app.SweepFieldButton.Text = 'Sweep Field';
            app.SaveSettingsButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.yFieldTable = uitable(app.SFieldJeffreyMEdeUIFigure);
            app.yFieldTable.ColumnName = {'By(t) in G'; 'Time (s)'; 'Steps'};
            app.yFieldTable.RowName = {};
            app.yFieldTable.ColumnEditable = true;
            app.yFieldTable.CellEditCallback = createCallbackFcn(app, @yFieldTableCellEdit, true);
            app.yFieldAxes = uiaxes(app.SFieldJeffreyMEdeUIFigure);
            xlabel(app.yFieldAxes, 'Time (s)')
            ylabel(app.yFieldAxes, 'y field (kG)')
            app.zFieldTable = uitable(app.SFieldJeffreyMEdeUIFigure);
            app.zFieldTable.ColumnName = {'Bz(t) in G'; 't_start'; 't_end'};
            app.zFieldTable.RowName = {};
            app.zFieldTable.ColumnEditable = true;
            app.zFieldTable.CellEditCallback = createCallbackFcn(app, @zFieldTableCellEdit, true);
            app.zFieldAxes = uiaxes(app.SFieldJeffreyMEdeUIFigure);
            xlabel(app.zFieldAxes, 'Time (s)')
            ylabel(app.zFieldAxes, 'z field (kG)')
            app.FieldButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.FieldButton.Text = '3D Field';
            app.SaveSweepButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.SaveSweepButton.Text = 'Save Sweep';
            app.HelpButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.DropDownLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.SFieldJeffreyMEdeUIFigure);
            app.DropDown.Items = {'Field (kG)', 'Current (A)'};
            app.DropDown.Value = 'Field (kG)';
            app.ToleranceEditFieldLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.SFieldJeffreyMEdeUIFigure, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.Value = 0.005;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mag_Field_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SFieldJeffreyMEdeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SFieldJeffreyMEdeUIFigure)
        end
    end
end